classdef myCobot_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        runsimButton                   matlab.ui.control.Button
        joint6Slider                   matlab.ui.control.Slider
        joint6SliderLabel              matlab.ui.control.Label
        joint5Slider                   matlab.ui.control.Slider
        joint5SliderLabel              matlab.ui.control.Label
        joint4Slider                   matlab.ui.control.Slider
        joint4SliderLabel              matlab.ui.control.Label
        joint3Slider                   matlab.ui.control.Slider
        joint3SliderLabel              matlab.ui.control.Label
        joint2Slider                   matlab.ui.control.Slider
        joint2SliderLabel              matlab.ui.control.Label
        joint1Slider                   matlab.ui.control.Slider
        joint1SliderLabel              matlab.ui.control.Label
        startstopserialButton          matlab.ui.control.StateButton
        RightPanel                     matlab.ui.container.Panel
        showmetheouputButton           matlab.ui.control.Button
        whatdoyouwanttherobottowriteEditField  matlab.ui.control.EditField
        whatdoyouwanttherobottowriteEditFieldLabel  matlab.ui.control.Label
        hellouserpushstartserialLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: startstopserialButton
        function startstopserialButtonValueChanged(app, event)
            value = app.startstopserialButton.Value;
            if value ==1
                try
                    % Replace 'COMx' with your actual serial port
                    botObj = serialport("COMx",115200);
                    fopen(app.SerialPort);
                    disp('Serial port connected.');
                    % You can also update a status label to show the connection status
                catch
                    disp('Failed to connect to the serial port.');
                end
            end
            if value==0
                flush(botObj)
            end
        end

        % Button pushed function: runsimButton
        function runsimButtonPushed(app, event)
        try
            % Specify the Simulink model file name
            modelName = 'MyCobot_Simscape.slx';

            % Run the Simulink model
            sim(modelName);

            % You can add additional code here to handle the results or update the GUI as needed
            disp('simulation has been successfully run.');
        catch
            disp('failed to run the simulation.');
        end
        end

        % Value changed function: joint1Slider
        function joint1SliderValueChanged(app, event)
            j1=app.joint1Slider.Value;
            set_param('MyCobot_SIMSCAPE/J1','Gain', num2str(j1));
            pause(1)
        end

        % Value changed function: joint2Slider
        function joint2SliderValueChanged(app, event)
            j2=app.joint2Slider.Value;
            set_param('MyCobot_SIMSCAPE/J2','Gain', num2str(j2));
        end

        % Value changed function: joint3Slider
        function joint3SliderValueChanged(app, event)
            j3=app.joint1Slider.Value;
            set_param('MyCobot_SIMSCAPE/J3','Gain', num2str(j3));
        end

        % Value changed function: joint4Slider
        function joint4SliderValueChanged(app, event)
            j4=app.joint1Slider.Value;
            set_param('MyCobot_SIMSCAPE/J4','Gain', num2str(j4));
        end

        % Value changed function: joint5Slider
        function joint5SliderValueChanged(app, event)
            j5=app.joint1Slider.Value;
            set_param('MyCobot_SIMSCAPE/J5','Gain', num2str(j5));
        end

        % Value changed function: joint6Slider
        function joint6SliderValueChanged(app, event)
            j6=app.joint1Slider.Value;
            set_param('MyCobot_SIMSCAPE/J6','Gain', num2str(j6));
        end

        % Value changed function: whatdoyouwanttherobottowriteEditField
        function whatdoyouwanttherobottowriteEditFieldValueChanged(app, event)

        end

        % Button pushed function: showmetheouputButton
        function showmetheouputButtonPushed(app, event)
            value = app.whatdoyouwanttherobottowriteEditField.Value;
            character=value;
            character_tracer;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {463, 463};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {265, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.startstopserialButton = uibutton(app.LeftPanel, 'state');
            app.startstopserialButton.ValueChangedFcn = createCallbackFcn(app, @startstopserialButtonValueChanged, true);
            app.startstopserialButton.Text = 'start/stop serial';
            app.joint1SliderLabel = uilabel(app.LeftPanel);
            app.joint1SliderLabel.Text = 'joint 1';
            app.joint1Slider = uislider(app.LeftPanel);
            app.joint1Slider.Limits = [-160 160];
            app.joint1Slider.ValueChangedFcn = createCallbackFcn(app, @joint1SliderValueChanged, true);
            app.joint2SliderLabel = uilabel(app.LeftPanel);
            app.joint2SliderLabel.Text = 'joint 2';
            app.joint2Slider = uislider(app.LeftPanel);
            app.joint2Slider.Limits = [-160 160];
            app.joint2Slider.ValueChangedFcn = createCallbackFcn(app, @joint2SliderValueChanged, true);
            app.joint3SliderLabel = uilabel(app.LeftPanel);
            app.joint3SliderLabel.Text = 'joint 3';
            app.joint3Slider = uislider(app.LeftPanel);
            app.joint3Slider.Limits = [-160 160];
            app.joint3Slider.ValueChangedFcn = createCallbackFcn(app, @joint3SliderValueChanged, true);
            app.joint4SliderLabel = uilabel(app.LeftPanel);
            app.joint4SliderLabel.Text = 'joint 4';
            app.joint4Slider = uislider(app.LeftPanel);
            app.joint4Slider.Limits = [-160 160];
            app.joint4Slider.ValueChangedFcn = createCallbackFcn(app, @joint4SliderValueChanged, true);
            app.joint5SliderLabel = uilabel(app.LeftPanel);
            app.joint5SliderLabel.Text = 'joint 5';
            app.joint5Slider = uislider(app.LeftPanel);
            app.joint5Slider.Limits = [-160 160];
            app.joint5Slider.ValueChangedFcn = createCallbackFcn(app, @joint5SliderValueChanged, true);
            app.joint6SliderLabel = uilabel(app.LeftPanel);
            app.joint6SliderLabel.Text = 'joint 6';
            app.joint6Slider = uislider(app.LeftPanel);
            app.joint6Slider.Limits = [-160 160];
            app.joint6Slider.ValueChangedFcn = createCallbackFcn(app, @joint6SliderValueChanged, true);
            app.runsimButton = uibutton(app.LeftPanel, 'push');
            app.runsimButton.ButtonPushedFcn = createCallbackFcn(app, @runsimButtonPushed, true);
            app.runsimButton.Text = 'run sim';
            app.RightPanel = uipanel(app.GridLayout);
            app.hellouserpushstartserialLabel = uilabel(app.RightPanel);
            app.hellouserpushstartserialLabel.Text = {'hello user,'; 'push start serial to start communication with the myCobot.'; 'click on ''run sim'' to start simulation.'; 'this app will be able to send commands to both your digital twin '; 'and real robot.'; ''; 'to command it to write different letters, type the letter you want below.'};
            app.whatdoyouwanttherobottowriteEditFieldLabel = uilabel(app.RightPanel);
            app.whatdoyouwanttherobottowriteEditFieldLabel.Text = 'what do you want the robot to write?';
            app.whatdoyouwanttherobottowriteEditField = uieditfield(app.RightPanel, 'text');
            app.whatdoyouwanttherobottowriteEditField.CharacterLimits = [0 1];
            app.whatdoyouwanttherobottowriteEditField.InputType = 'letters';
            app.whatdoyouwanttherobottowriteEditField.ValueChangedFcn = createCallbackFcn(app, @whatdoyouwanttherobottowriteEditFieldValueChanged, true);
            app.showmetheouputButton = uibutton(app.RightPanel, 'push');
            app.showmetheouputButton.ButtonPushedFcn = createCallbackFcn(app, @showmetheouputButtonPushed, true);
            app.showmetheouputButton.Text = 'show me the ouput!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myCobot_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end